classdef dicegame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        Player1              matlab.ui.container.Panel
        ROLLButton           matlab.ui.control.Button
        SELECTSwitchLabel    matlab.ui.control.Label
        SELECTSwitch         matlab.ui.control.RockerSwitch
        ScoreLabel           matlab.ui.control.Label
        ScoreValue           matlab.ui.control.Label
        STARTButton          matlab.ui.control.Button
        ReadyLabel           matlab.ui.control.Label
        Image                matlab.ui.control.Image
        CenterPanel          matlab.ui.container.Panel
        Background           matlab.ui.control.Image
        HealthBar1           matlab.ui.control.Image
        HealthBar2           matlab.ui.control.Image
        HealthLabel          matlab.ui.control.Label
        HealthLabel_2        matlab.ui.control.Label
        HealthValue_2        matlab.ui.control.Label
        HealthValue          matlab.ui.control.Label
        Label                matlab.ui.control.Label
        Label_2              matlab.ui.control.Label
        Player1Label         matlab.ui.control.Label
        Player2Label         matlab.ui.control.Label
        WinLabel             matlab.ui.control.Label
        BattleLogButton      matlab.ui.control.Button
        BattleLog            matlab.ui.control.Table
        Player2              matlab.ui.container.Panel
        ROLLButton_2         matlab.ui.control.Button
        SELECTSwitch_2       matlab.ui.control.RockerSwitch
        SELECTSwitch_2Label  matlab.ui.control.Label
        ScoreLabel_2         matlab.ui.control.Label
        ScoreValue_2         matlab.ui.control.Label
        STARTButton_2        matlab.ui.control.Button
        ReadyLabel_2         matlab.ui.control.Label
        Image2               matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ROLLButton
        function ROLLButtonPushed(app, event)
            global number1 
            number1 = randi(6);
            
            global number2
            
            set(app.ROLLButton, 'Enable', 'off');
            
          % Player 1's Dice Roll
            switch number1
                case 1
                    app.Image.ImageSource = imread("diceImages/1.jpg");
                case 2
                    app.Image.ImageSource = imread("diceImages/2.jpg");
                case 3
                    app.Image.ImageSource = imread("diceImages/3.jpg");
                case 4
                    app.Image.ImageSource = imread("diceImages/4.jpg");
                case 5
                    app.Image.ImageSource = imread("diceImages/5.jpg");
                otherwise
                    app.Image.ImageSource = imread("diceImages/6.jpg");
            end
            
            % Health Bar Display function for Player 1
            function DISPLAYPlayer1HealthBar(health)
                switch health
                    case 0
                        app.HealthBar1.ImageSource = imread("diceImages/player1hp0.png");
                    case 1
                        app.HealthBar1.ImageSource = imread("diceImages/player1hp1.png");
                    case 2
                        app.HealthBar1.ImageSource = imread("diceImages/player1hp2.png");
                    case 3
                        app.HealthBar1.ImageSource = imread("diceImages/player1hp3.png");
                    case 4
                        app.HealthBar1.ImageSource = imread("diceImages/player1hp4.png");
                    case 5
                        app.HealthBar1.ImageSource = imread("diceImages/player1hp5.png");
                    case 6
                        app.HealthBar1.ImageSource = imread("diceImages/player1hp6.png");
                    case 7
                        app.HealthBar1.ImageSource = imread("diceImages/player1hp7.png");
                    case 8
                        app.HealthBar1.ImageSource = imread("diceImages/player1hp8.png");
                    case 9
                        app.HealthBar1.ImageSource = imread("diceImages/player1hp9.png");
                    otherwise  
                        app.HealthBar1.ImageSource = imread("diceImages/player1hp10.png");
                end
            end
            
            % Health Bar Display function for Player 2
            function DISPLAYPlayer2HealthBar(health)
                switch health
                    case 0
                        app.HealthBar2.ImageSource = imread("diceImages/hp0.png");
                    case 1
                        app.HealthBar2.ImageSource = imread("diceImages/hp1.png");
                    case 2
                        app.HealthBar2.ImageSource = imread("diceImages/hp2.png");
                    case 3
                        app.HealthBar2.ImageSource = imread("diceImages/hp3.png");
                    case 4
                        app.HealthBar2.ImageSource = imread("diceImages/hp4.png");
                    case 5
                        app.HealthBar2.ImageSource = imread("diceImages/hp5.png");
                    case 6
                        app.HealthBar2.ImageSource = imread("diceImages/hp6.png");
                    case 7
                        app.HealthBar2.ImageSource = imread("diceImages/hp7.png");
                    case 8
                        app.HealthBar2.ImageSource = imread("diceImages/hp8.png");
                    case 9
                        app.HealthBar2.ImageSource = imread("diceImages/hp9.png");
                    otherwise  
                        app.HealthBar2.ImageSource = imread("diceImages/hp10.png");
                end
            end
            
            % Play Sound File function
            function PLAYSoundFile(filename)
                [y,Fs] = audioread(filename);
                sound(y,Fs);
                clear y Fs;
            end
            
   % WARNING: IF YOU ALTER THE BELOW CODE, MAKE SURE THAT PLAYER 2'S CODE 
   % IS ALSO CHANGED!
            
            if number1 > 0 & number2 > 0
              % Player 1 has the highest roll:
                if number1 > number2
                    switch app.SELECTSwitch.Value
                      % If Player 1 is attacking:
                        case 'ATTACK'
                            conv2num = str2double(app.HealthValue_2.Text);
                            newValue = conv2num - (number1 - number2);
                            DISPLAYPlayer2HealthBar(newValue);
                            conv2char = string(newValue);
                            app.HealthValue_2.Text = conv2char;
                            PLAYSoundFile("diceSound/hiss.mp3");
                            
                          % To prevent health from dropping below zero: 
                            if str2double(app.HealthValue_2.Text) < 0
                                app.HealthValue_2.Text = "0";
                            end
                           
                          % Update Battle Log
                            app.BattleLog.Data = [app.BattleLog.Data;{number1 number2}];
                            
                          % Reset board:
                            number1 = 0;
                            number2 = 0; 
                            pause(1);
                            app.Image.ImageSource = imread("diceImages/blank.jpg");
                            app.Image2.ImageSource = imread("diceImages/blank.jpg");
                            set(app.ROLLButton, 'Enable', 'on');
                            set(app.ROLLButton_2, 'Enable', 'on');
                            
                      % If Player 1 is healing:
                        case 'HEAL'
                            PLAYSoundFile("diceSound/bark.mp3");
                            conv2num = str2double(app.HealthValue.Text);
                            newValue = conv2num + (number1 - number2);
                            DISPLAYPlayer1HealthBar(newValue);
                            conv2char = string(newValue);
                            app.HealthValue.Text = conv2char;
                            
                          % To prevent health from going above ten:                             
                            if str2double(app.HealthValue.Text) > 10
                                app.HealthValue.Text = "10";
                            end
                          
                          % Update Battle Log
                            app.BattleLog.Data = [app.BattleLog.Data;{number1 number2}];
                          
                          % Reset board:
                            number1 = 0;
                            number2 = 0; 
                            pause(1);
                            app.Image.ImageSource = imread("diceImages/blank.jpg");
                            app.Image2.ImageSource = imread("diceImages/blank.jpg");
                            set(app.ROLLButton, 'Enable', 'on');
                            set(app.ROLLButton_2, 'Enable', 'on');
                    end
                
              % Player 2 has the highest roll:    
                else
                    switch app.SELECTSwitch_2.Value
                      % If Player 2 is attacking:
                        case 'ATTACK'
                            conv2num = str2double(app.HealthValue.Text);
                            newValue = conv2num - (number2 - number1);
                            DISPLAYPlayer1HealthBar(newValue);
                            conv2char = string(newValue);
                            app.HealthValue.Text = conv2char;
                            PLAYSoundFile("diceSound/whimper.wav");
                            
                          % To prevent health from dropping below zero:
                            if str2double(app.HealthValue.Text) < 0
                                app.HealthValue.Text = "0";
                            end
                            
                          % Update Battle Log
                            app.BattleLog.Data = [app.BattleLog.Data;{number1 number2}];
                            
                          % Reset board:
                            number1 = 0;
                            number2 = 0;
                            pause(1);
                            app.Image.ImageSource = imread("diceImages/blank.jpg");
                            app.Image2.ImageSource = imread("diceImages/blank.jpg");
                            set(app.ROLLButton, 'Enable', 'on');
                            set(app.ROLLButton_2, 'Enable', 'on');
                            
                      % If Player 2 is healing:    
                        case 'HEAL'
                            PLAYSoundFile("diceSound/lunge.mp3");
                            conv2num = str2double(app.HealthValue_2.Text);
                            newValue = conv2num + (number2 - number1);
                            DISPLAYPlayer2HealthBar(newValue);
                            conv2char = string(newValue);
                            app.HealthValue_2.Text = conv2char;
                            
                          % To prevent health from going above ten:
                            if str2double(app.HealthValue_2.Text) > 10
                                app.HealthValue_2.Text = "10";
                            end
                            
                          % Update Battle Log
                            app.BattleLog.Data = [app.BattleLog.Data;{number1 number2}];
                            
                          % Reset board:
                            number1 = 0;
                            number2 = 0;                                       
                            pause(1);
                            app.Image.ImageSource = imread("diceImages/blank.jpg");
                            app.Image2.ImageSource = imread("diceImages/blank.jpg");
                            set(app.ROLLButton, 'Enable', 'on');
                            set(app.ROLLButton_2, 'Enable', 'on');
                    end
                end
            end
            
          % Player 1 loses:
            if str2double(app.HealthValue.Text) <= 0
                app.HealthValue.Text = "0";
                DISPLAYPlayer1HealthBar(0);
                PLAYSoundFile("diceSound/victory.mp3");
                score2num = str2double(app.ScoreValue_2.Text);
                newScore = score2num + 1;
                score2string = string(newScore);
                app.ScoreValue_2.Text = score2string;
                app.WinLabel.Text(8) = "2";
                set(app.WinLabel, 'Visible', 'on');
                
              % Reset Game:
                number1 = 0;
                number2 = 0;                
                set(app.ROLLButton, 'Enable', 'off');
                set(app.ROLLButton_2, 'Enable', 'off');
                pause(3);
                app.Image.ImageSource = imread("diceImages/blank.jpg");
                app.Image2.ImageSource = imread("diceImages/blank.jpg");
                DISPLAYPlayer1HealthBar(10);
                DISPLAYPlayer2HealthBar(10);
                app.HealthValue.Text = "10";
                app.HealthValue_2.Text = "10";
                set(app.ROLLButton, 'Enable', 'on');
                set(app.ROLLButton_2, 'Enable', 'on');
                set(app.WinLabel, 'Visible', 'off');
                app.BattleLog.Data = {};
            end
            
          % Player 2 loses:
            if str2double(app.HealthValue_2.Text) <= 0
                app.HealthValue_2.Text = "0";
                DISPLAYPlayer2HealthBar(0);
                PLAYSoundFile("diceSound/victory.mp3");
                score2num = str2double(app.ScoreValue.Text);
                newScore = score2num + 1;
                score2string = string(newScore);
                app.ScoreValue.Text = score2string;
                app.WinLabel.Text(8) = "1";
                set(app.WinLabel, 'Visible', 'on');
                
              % Reset Game:
                number1 = 0;
                number2 = 0;                
                set(app.ROLLButton, 'Enable', 'off');
                set(app.ROLLButton_2, 'Enable', 'off');                                       
                pause(3);
                app.Image.ImageSource = imread("diceImages/blank.jpg");
                app.Image2.ImageSource = imread("diceImages/blank.jpg");
                DISPLAYPlayer1HealthBar(10);
                DISPLAYPlayer2HealthBar(10);
                app.HealthValue.Text = "10";
                app.HealthValue_2.Text = "10";
                set(app.ROLLButton, 'Enable', 'on');
                set(app.ROLLButton_2, 'Enable', 'on');
                set(app.WinLabel, 'Visible', 'off');
                app.BattleLog.Data = {};
            end
        end

        % Button pushed function: ROLLButton_2
        function ROLLButton_2Pushed(app, event)
            global number2
            number2 = randi(6);
            
            global number1
            
            set(app.ROLLButton_2, 'Enable', 'off');
            
            %Player 2's Dice Roll:
            switch number2
                case 1
                    app.Image2.ImageSource = imread("diceImages/1.jpg");
                case 2
                    app.Image2.ImageSource = imread("diceImages/2.jpg");
                case 3
                    app.Image2.ImageSource = imread("diceImages/3.jpg");
                case 4
                    app.Image2.ImageSource = imread("diceImages/4.jpg");
                case 5
                    app.Image2.ImageSource = imread("diceImages/5.jpg");
                otherwise
                    app.Image2.ImageSource = imread("diceImages/6.jpg");
            end
            
            % Health Bar Display function for Player 1
            function DISPLAYPlayer1HealthBar(health)
                switch health
                    case 0
                        app.HealthBar1.ImageSource = imread("diceImages/player1hp0.png");
                    case 1
                        app.HealthBar1.ImageSource = imread("diceImages/player1hp1.png");
                    case 2
                        app.HealthBar1.ImageSource = imread("diceImages/player1hp2.png");
                    case 3
                        app.HealthBar1.ImageSource = imread("diceImages/player1hp3.png");
                    case 4
                        app.HealthBar1.ImageSource = imread("diceImages/player1hp4.png");
                    case 5
                        app.HealthBar1.ImageSource = imread("diceImages/player1hp5.png");
                    case 6
                        app.HealthBar1.ImageSource = imread("diceImages/player1hp6.png");
                    case 7
                        app.HealthBar1.ImageSource = imread("diceImages/player1hp7.png");
                    case 8
                        app.HealthBar1.ImageSource = imread("diceImages/player1hp8.png");
                    case 9
                        app.HealthBar1.ImageSource = imread("diceImages/player1hp9.png");
                    otherwise  
                        app.HealthBar1.ImageSource = imread("diceImages/player1hp10.png");
                end
            end
            
            % Health Bar Display function for Player 2
            function DISPLAYPlayer2HealthBar(health)
                switch health
                    case 0
                        app.HealthBar2.ImageSource = imread("diceImages/hp0.png");
                    case 1
                        app.HealthBar2.ImageSource = imread("diceImages/hp1.png");
                    case 2
                        app.HealthBar2.ImageSource = imread("diceImages/hp2.png");
                    case 3
                        app.HealthBar2.ImageSource = imread("diceImages/hp3.png");
                    case 4
                        app.HealthBar2.ImageSource = imread("diceImages/hp4.png");
                    case 5
                        app.HealthBar2.ImageSource = imread("diceImages/hp5.png");
                    case 6
                        app.HealthBar2.ImageSource = imread("diceImages/hp6.png");
                    case 7
                        app.HealthBar2.ImageSource = imread("diceImages/hp7.png");
                    case 8
                        app.HealthBar2.ImageSource = imread("diceImages/hp8.png");
                    case 9
                        app.HealthBar2.ImageSource = imread("diceImages/hp9.png");
                    otherwise  
                        app.HealthBar2.ImageSource = imread("diceImages/hp10.png");
                end
            end
            
            % Play Sound File function
            function PLAYSoundFile(filename)
                [y,Fs] = audioread(filename);
                sound(y,Fs);
                clear y Fs;
            end
            
   %  The rest of the code is the same as Player 1's, so that way it doesn't
   %  matter which person rolls first.
   
   %  WARNING: IF YOU ALTER THE BELOW CODE, MAKE SURE THAT PLAYER 1'S CODE 
   %  IS ALSO CHANGED!
            
            if number1 > 0 & number2 > 0
              % Player 1 has the highest roll:
                if number1 > number2
                    switch app.SELECTSwitch.Value
                      % If Player 1 is attacking:
                        case 'ATTACK'
                            conv2num = str2double(app.HealthValue_2.Text);
                            newValue = conv2num - (number1 - number2);
                            DISPLAYPlayer2HealthBar(newValue);
                            conv2char = string(newValue);
                            app.HealthValue_2.Text = conv2char;
                            PLAYSoundFile("diceSound/hiss.mp3");
                            
                          % To prevent health from dropping below zero: 
                            if str2double(app.HealthValue_2.Text) < 0
                                app.HealthValue_2.Text = "0";
                            end
                           
                          % Update Battle Log
                            app.BattleLog.Data = [app.BattleLog.Data;{number1 number2}];
                            
                          % Reset board:
                            number1 = 0;
                            number2 = 0; 
                            pause(1);
                            app.Image.ImageSource = imread("diceImages/blank.jpg");
                            app.Image2.ImageSource = imread("diceImages/blank.jpg");
                            set(app.ROLLButton, 'Enable', 'on');
                            set(app.ROLLButton_2, 'Enable', 'on');
                            
                      % If Player 1 is healing:
                        case 'HEAL'
                            PLAYSoundFile("diceSound/bark.mp3");
                            conv2num = str2double(app.HealthValue.Text);
                            newValue = conv2num + (number1 - number2);
                            DISPLAYPlayer1HealthBar(newValue);
                            conv2char = string(newValue);
                            app.HealthValue.Text = conv2char;
                            
                          % To prevent health from going above ten:                             
                            if str2double(app.HealthValue.Text) > 10
                                app.HealthValue.Text = "10";
                            end
                         
                          % Update Battle Log
                            app.BattleLog.Data = [app.BattleLog.Data;{number1 number2}];
                            
                          % Reset board:
                            number1 = 0;
                            number2 = 0; 
                            pause(1);
                            app.Image.ImageSource = imread("diceImages/blank.jpg");
                            app.Image2.ImageSource = imread("diceImages/blank.jpg");
                            set(app.ROLLButton, 'Enable', 'on');
                            set(app.ROLLButton_2, 'Enable', 'on');
                    end
                
              % Player 2 has the highest roll:    
                else
                    switch app.SELECTSwitch_2.Value
                      % If Player 2 is attacking:
                        case 'ATTACK'
                            conv2num = str2double(app.HealthValue.Text);
                            newValue = conv2num - (number2 - number1);
                            DISPLAYPlayer1HealthBar(newValue);
                            conv2char = string(newValue);
                            app.HealthValue.Text = conv2char;
                            PLAYSoundFile("diceSound/whimper.wav");
                            
                          % To prevent health from dropping below zero:
                            if str2double(app.HealthValue.Text) < 0
                                app.HealthValue.Text = "0";
                            end
                            
                          % Update Battle Log
                            app.BattleLog.Data = [app.BattleLog.Data;{number1 number2}];
                            
                          % Reset board:
                            number1 = 0;
                            number2 = 0;
                            pause(1);
                            app.Image.ImageSource = imread("diceImages/blank.jpg");
                            app.Image2.ImageSource = imread("diceImages/blank.jpg");
                            set(app.ROLLButton, 'Enable', 'on');
                            set(app.ROLLButton_2, 'Enable', 'on');
                            
                      % If Player 2 is healing:    
                        case 'HEAL'
                            PLAYSoundFile("diceSound/lunge.mp3");
                            conv2num = str2double(app.HealthValue_2.Text);
                            newValue = conv2num + (number2 - number1);
                            DISPLAYPlayer2HealthBar(newValue);
                            conv2char = string(newValue);
                            app.HealthValue_2.Text = conv2char;
                            
                          % To prevent health from going above ten:
                            if str2double(app.HealthValue_2.Text) > 10
                                app.HealthValue_2.Text = "10";
                            end
                            
                          % Update Battle Log
                            app.BattleLog.Data = [app.BattleLog.Data;{number1 number2}];
                            
                          % Reset board:
                            number1 = 0;
                            number2 = 0;                                       
                            pause(1);
                            app.Image.ImageSource = imread("diceImages/blank.jpg");
                            app.Image2.ImageSource = imread("diceImages/blank.jpg");
                            set(app.ROLLButton, 'Enable', 'on');
                            set(app.ROLLButton_2, 'Enable', 'on');
                    end
                end
            end
            
          % Player 1 loses:
            if str2double(app.HealthValue.Text) <= 0
                app.HealthValue.Text = "0";
                DISPLAYPlayer1HealthBar(0);
                PLAYSoundFile("diceSound/victory.mp3");
                score2num = str2double(app.ScoreValue_2.Text);
                newScore = score2num + 1;
                score2string = string(newScore);
                app.ScoreValue_2.Text = score2string;
                app.WinLabel.Text(8) = "2";
                set(app.WinLabel, 'Visible', 'on');
                
              % Reset Game:
                number1 = 0;
                number2 = 0;                
                set(app.ROLLButton, 'Enable', 'off');
                set(app.ROLLButton_2, 'Enable', 'off');
                pause(3);
                app.Image.ImageSource = imread("diceImages/blank.jpg");
                app.Image2.ImageSource = imread("diceImages/blank.jpg");
                DISPLAYPlayer1HealthBar(10);
                DISPLAYPlayer2HealthBar(10);
                app.HealthValue.Text = "10";
                app.HealthValue_2.Text = "10";
                set(app.ROLLButton, 'Enable', 'on');
                set(app.ROLLButton_2, 'Enable', 'on');
                set(app.WinLabel, 'Visible', 'off');
                app.BattleLog.Data = {};
            end
            
          % Player 2 loses:
            if str2double(app.HealthValue_2.Text) <= 0
                app.HealthValue_2.Text = "0";
                DISPLAYPlayer2HealthBar(0);
                PLAYSoundFile("diceSound/victory.mp3");
                score2num = str2double(app.ScoreValue.Text);
                newScore = score2num + 1;
                score2string = string(newScore);
                app.ScoreValue.Text = score2string;
                app.WinLabel.Text(8) = "1";
                set(app.WinLabel, 'Visible', 'on');
                
              % Reset Game:
                number1 = 0;
                number2 = 0;                
                set(app.ROLLButton, 'Enable', 'off');
                set(app.ROLLButton_2, 'Enable', 'off');                                       
                pause(3);
                app.Image.ImageSource = imread("diceImages/blank.jpg");
                app.Image2.ImageSource = imread("diceImages/blank.jpg");
                DISPLAYPlayer1HealthBar(10);
                DISPLAYPlayer2HealthBar(10);
                app.HealthValue.Text = "10";
                app.HealthValue_2.Text = "10";
                set(app.ROLLButton, 'Enable', 'on');
                set(app.ROLLButton_2, 'Enable', 'on');
                set(app.WinLabel, 'Visible', 'off');
                app.BattleLog.Data = {};
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            set(app.STARTButton, 'Enable', 'off');
            
            if app.STARTButton.Enable == 'off' & app.STARTButton_2.Enable == 'off'
                set(app.STARTButton, 'Visible', 'off');
                set(app.STARTButton_2, 'Visible', 'off');
                set(app.ReadyLabel, 'Visible', 'off');
                set(app.ReadyLabel_2, 'Visible', 'off');
                
                set(app.ROLLButton, 'Visible', 'on');
                set(app.SELECTSwitch, 'Visible', 'on');
                set(app.SELECTSwitchLabel, 'Visible', 'on');
                set(app.HealthValue, 'Visible', 'on');
                                
                set(app.ROLLButton_2, 'Visible', 'on');
                set(app.SELECTSwitch_2, 'Visible', 'on');
                set(app.SELECTSwitch_2Label, 'Visible', 'on');
                set(app.HealthValue_2, 'Visible', 'on');
                
                app.Image.ImageSource = imread("diceImages/blank.jpg");
                app.Image2.ImageSource = imread("diceImages/blank.jpg");
                set(app.Image, 'Visible', 'on');
                set(app.Image2, 'Visible', 'on');
            end
        end

        % Button pushed function: STARTButton_2
        function STARTButton_2Pushed(app, event)
            set(app.STARTButton_2, 'Enable', 'off');
          
            if app.STARTButton.Enable == 'off' & app.STARTButton_2.Enable == 'off'
                set(app.STARTButton, 'Visible', 'off');
                set(app.STARTButton_2, 'Visible', 'off');
                set(app.ReadyLabel, 'Visible', 'off');
                set(app.ReadyLabel_2, 'Visible', 'off');
                
                set(app.ROLLButton, 'Visible', 'on');
                set(app.SELECTSwitch, 'Visible', 'on');
                set(app.SELECTSwitchLabel, 'Visible', 'on');
                set(app.HealthValue, 'Visible', 'on');
                set(app.HealthLabel, 'Visible', 'on');              
                set(app.ScoreValue, 'Visible', 'on');
                set(app.ScoreLabel, 'Visible', 'on');
                                
                set(app.ROLLButton_2, 'Visible', 'on');
                set(app.SELECTSwitch_2, 'Visible', 'on');
                set(app.SELECTSwitch_2Label, 'Visible', 'on');
                set(app.HealthValue_2, 'Visible', 'on');
                set(app.HealthLabel_2, 'Visible', 'on');                
                set(app.ScoreValue_2, 'Visible', 'on');
                set(app.ScoreLabel_2, 'Visible', 'on');
                
                app.Image.ImageSource = imread("diceImages/blank.jpg");
                app.Image2.ImageSource = imread("diceImages/blank.jpg");
                set(app.Image, 'Visible', 'on');
                set(app.Image2, 'Visible', 'on');
            end
        end

        % Button pushed function: BattleLogButton
        function BattleLogButtonPushed(app, event)
            if app.BattleLog.Visible == false
                set(app.BattleLog, 'Visible', 'on');
            else
                set(app.BattleLog, 'Visible', 'off');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {432, 432, 432};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.Player1.Layout.Row = 2;
                app.Player1.Layout.Column = 1;
                app.Player2.Layout.Row = 3;
                app.Player2.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {432, 432};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.Player1.Layout.Row = 2;
                app.Player1.Layout.Column = 1;
                app.Player2.Layout.Row = 2;
                app.Player2.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {163, '1x', 163};
                app.Player1.Layout.Row = 1;
                app.Player1.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.Player2.Layout.Row = 1;
                app.Player2.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Player1 = uipanel(app.GridLayout);
            app.ROLLButton = uibutton(app.Player1, 'push');
            app.ROLLButton.ButtonPushedFcn = createCallbackFcn(app, @ROLLButtonPushed, true);
            app.ROLLButton.Visible = 'off';
            app.ROLLButton.Text = 'ROLL!';
            app.SELECTSwitchLabel = uilabel(app.Player1);
            app.SELECTSwitchLabel.Visible = 'off';
            app.SELECTSwitchLabel.Text = 'SELECT:';
            app.SELECTSwitch = uiswitch(app.Player1, 'rocker');
            app.SELECTSwitch.Items = {'HEAL', 'ATTACK'};
            app.SELECTSwitch.Visible = 'off';
            app.SELECTSwitch.Value = 'ATTACK';
            app.ScoreLabel = uilabel(app.Player1);
            app.ScoreLabel.Text = 'Score:';
            app.ScoreValue = uilabel(app.Player1);
            app.ScoreValue.Text = '0';
            app.STARTButton = uibutton(app.Player1, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.ReadyLabel = uilabel(app.Player1);
            app.ReadyLabel.Text = 'Ready?';
            app.Image = uiimage(app.Player1);
            app.Image.Visible = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.Background = uiimage(app.CenterPanel);
            app.Background.ImageSource = 'back.png';
            app.HealthBar1 = uiimage(app.CenterPanel);
            app.HealthBar1.ImageSource = 'player1hp10.png';
            app.HealthBar2 = uiimage(app.CenterPanel);
            app.HealthBar2.ImageSource = 'hp10.png';
            app.HealthLabel = uilabel(app.CenterPanel);
            app.HealthLabel.Text = 'Health:';
            app.HealthLabel_2 = uilabel(app.CenterPanel);
            app.HealthLabel_2.Text = 'Health:';
            app.HealthValue_2 = uilabel(app.CenterPanel);
            app.HealthValue_2.Visible = 'off';
            app.HealthValue_2.Text = '10';
            app.HealthValue = uilabel(app.CenterPanel);
            app.HealthValue.Visible = 'off';
            app.HealthValue.Text = '10';
            app.Label = uilabel(app.CenterPanel);
            app.Label.Text = '/10';
            app.Label_2 = uilabel(app.CenterPanel);
            app.Label_2.Text = '/10';
            app.Player1Label = uilabel(app.CenterPanel);
            app.Player1Label.Text = 'Player 1';
            app.Player2Label = uilabel(app.CenterPanel);
            app.Player2Label.Text = 'Player 2';
            app.WinLabel = uilabel(app.CenterPanel);
            app.WinLabel.Visible = 'off';
            app.WinLabel.Text = 'PLAYER _ WINS!';
            app.BattleLogButton = uibutton(app.CenterPanel, 'push');
            app.BattleLogButton.ButtonPushedFcn = createCallbackFcn(app, @BattleLogButtonPushed, true);
            app.BattleLogButton.Text = 'Battle Log';
            app.BattleLog = uitable(app.CenterPanel);
            app.BattleLog.ColumnName = {'Player 1 Rolls'; 'Player 2 Rolls'};
            app.BattleLog.RowName = {};
            app.BattleLog.Visible = 'off';
            app.Player2 = uipanel(app.GridLayout);
            app.ROLLButton_2 = uibutton(app.Player2, 'push');
            app.ROLLButton_2.ButtonPushedFcn = createCallbackFcn(app, @ROLLButton_2Pushed, true);
            app.ROLLButton_2.Visible = 'off';
            app.ROLLButton_2.Text = 'ROLL!';
            app.SELECTSwitch_2 = uiswitch(app.Player2, 'rocker');
            app.SELECTSwitch_2.Items = {'HEAL', 'ATTACK'};
            app.SELECTSwitch_2.Visible = 'off';
            app.SELECTSwitch_2.Value = 'ATTACK';
            app.SELECTSwitch_2Label = uilabel(app.Player2);
            app.SELECTSwitch_2Label.Visible = 'off';
            app.SELECTSwitch_2Label.Text = 'SELECT:';
            app.ScoreLabel_2 = uilabel(app.Player2);
            app.ScoreLabel_2.Text = 'Score:';
            app.ScoreValue_2 = uilabel(app.Player2);
            app.ScoreValue_2.Text = '0';
            app.STARTButton_2 = uibutton(app.Player2, 'push');
            app.STARTButton_2.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_2Pushed, true);
            app.STARTButton_2.Text = 'START';
            app.ReadyLabel_2 = uilabel(app.Player2);
            app.ReadyLabel_2.Text = 'Ready?';
            app.Image2 = uiimage(app.Player2);
            app.Image2.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dicegame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end